classdef Neuron_Classifier2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LeftGrid                       matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        resetallButton                 matlab.ui.control.Button
        excludeButton                  matlab.ui.control.Button
        nextButton                     matlab.ui.control.Button
        previousButton                 matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        maximalgapsizemEditField       matlab.ui.control.NumericEditField
        maximalgapsizemEditFieldLabel  matlab.ui.control.Label
        NeuritethicknessmEditFieldLabel  matlab.ui.control.Label
        NeuritethicknessmEditField     matlab.ui.control.NumericEditField
        NeuronsegmenterLabel           matlab.ui.control.Label
        PreprocessingLabel             matlab.ui.control.Label
        LoadrawimagesButton            matlab.ui.control.Button
        SegmentationGrid               matlab.ui.container.GridLayout
        GapbridgingCheckBox            matlab.ui.control.CheckBox
        SegmentneuronsButton           matlab.ui.control.Button
        PreprocessingGrid              matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        SEsizeEditField                matlab.ui.control.NumericEditField
        SEsizeEditFieldLabel           matlab.ui.control.Label
        NoiseremovalusingstructuralelementButton  matlab.ui.control.Button
        SegmentationLabel              matlab.ui.control.Label
        Image_Segmented                matlab.ui.control.UIAxes
        Image_Input                    matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        ClassificationGrid             matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        CloseButton                    matlab.ui.control.Button
        ClassificationButton           matlab.ui.control.Button
        NeuronclassifierLabel          matlab.ui.control.Label
        LoadSegmentedGrid              matlab.ui.container.GridLayout
        LoadsegmentedimagesButton      matlab.ui.control.Button
        pixelsizepxmEditFieldLabel     matlab.ui.control.Label
        pixelsizepxmEditField          matlab.ui.control.NumericEditField
        FindBranchesGrid               matlab.ui.container.GridLayout
        FindsomaneuritesandbranchesButton  matlab.ui.control.Button
        minimalBranchLengthEditField   matlab.ui.control.NumericEditField
        minimalbranchlengthmEditFieldLabel  matlab.ui.control.Label
        SomaAxonGrid                   matlab.ui.container.GridLayout
        Image_soma                     matlab.ui.control.UIAxes
        Image_axon                     matlab.ui.control.UIAxes
        Image_final_classified         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        WorkDir = pwd; % Home directory
        FunctDir = addpath('./functions'); % Dummy variable to add functions folder to search path
        SaveDir = pwd; % Where the image-files are located
        
        
        Raw_Images % cell for input images
        TifFiles % names of input images
        PixSizes % pixel sizes of input images
        pix_size = 0.2261; % better: define pixel size during load

        SEelement = 'Circle' % Structural element for noise removal
        SE_size_preMorpho = 2 % Structural element size
        smoothed_by_Morpho_images % cell for preprocessed images

        thickness_micron = 1 % neurite thickness input
        Segmented_Images % cell for segmented images
        gap_bridge_check = 1 % Gap bridging checkbox
        gap_size_micron = 6 % Maximal gap size input

        MIN_LEN_micron = 10 % Minimal branch length
        Classified_processes % struct for all classified processes of the neuron
        per_neuron_results = table;

        index = 0; % image index
        excl_counter = 0; % number of excluded images
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadrawimagesButton
        function load_raw_images(app, event)
            % Create progress bar
            app.LoadrawimagesButton.Text = 'Processing...';
            app.LoadrawimagesButton.IconAlignment = 'bottom';
            wbar = permute(repmat(app.LoadrawimagesButton.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.LoadrawimagesButton.Icon = wbar;


            %% Load a Set of image files
            [~, pNameSeg] = uigetfile('*.tif');
            app.SaveDir = pNameSeg;
            cd(app.SaveDir);
            app.TifFiles = dir('*.tif');
            numfiles = length(app.TifFiles);
            mydata = cell(1, numfiles);
            for k = 1:numfiles
                mydata{k} = imread(app.TifFiles(k).name);
            end
            
            % assume no missing metadata on pixel sizes
            pixSize_metadata_missing = false;
            
            %% Converting RGB and/or Multilayer .tif files to single layer binary .tif files
            for k = 1:numfiles

                % Update progress bar
                currentProg = min(round((size(wbar,2)-2)*(k/numfiles)),size(wbar,2)-2);
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.LoadrawimagesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.001);
                
                % Get metadata
                infoSeg = imfinfo(app.TifFiles(k).name);
                
                % Get pixel size if it is encoded in the tif (and if its
                % values are plausible)
                if (~isempty(infoSeg.XResolution) & infoSeg.XResolution < 50)
                    app.PixSizes{k} = 1/infoSeg.XResolution;
                    
                else           
                    app.PixSizes{k} = app.pix_size;
                    disp([app.TifFiles(k).name,' did not contain a pixel size in the metadata. Pixel size was set to the default value: ', num2str(app.pix_size), ' px/µm'])
                    pixSize_metadata_missing = true;
                    
                end
                
                num_layers = numel(infoSeg);
                BW = zeros(infoSeg(1).Height, infoSeg(1).Width);
                if num_layers == 1                % If we have a single binary image
                    BW = mydata{k};
                    if size(BW,3) == 3          % If our raw binary image is an rgb
                        BW = rgb2gray(BW);
                    end
                else                              % If the raw binary image contains several layers
                    for L = 1:num_layers
                        BW = BW | imread(app.TifFiles(k).name, L, 'Info', infoSeg);
                    end
                end
                
                
                app.Raw_Images{k} = BW;
                imshow(app.Raw_Images{k}, 'Parent', app.Image_Input);   % Showing the raw image(s)


            end
            
            
            disp('**********************************');
            disp('Image(s) has(have) been uploaded!');
            disp('**********************************');

            if (pixSize_metadata_missing)
                msgbox('Some images did not contain a pixel size in their metadata. The prespecified pixel size was used for those')
            end
            
            cd(app.SaveDir);

            % remove progress bar
            app.LoadrawimagesButton.Icon = '';
            app.LoadrawimagesButton.Text = 'Load raw images';

            % Activate subsequent steps
            app.NoiseremovalusingstructuralelementButton.Enable = 'on';
            app.DropDown.Enable = 'on';
            app.SEsizeEditField.Enable = 'on';
            app.SegmentneuronsButton.Enable = 'on';
            app.GapbridgingCheckBox.Enable = 'on';
            app.NeuritethicknessmEditField.Enable = 'on';
            app.maximalgapsizemEditField.Enable = 'on';
            app.LoadsegmentedimagesButton.Enable = 'off';
            
        end

        % Value changed function: SEsizeEditField
        function SEsizeEditFieldValueChanged(app, event)
            app.SE_size_preMorpho = app.SEsizeEditField.Value;
            
        end

        % Value changed function: NeuritethicknessmEditField
        function NeuritethicknessmEditFieldValueChanged(app, event)
            app.thickness_micron = app.NeuritethicknessmEditField.Value;

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.SEelement = app.DropDown.Value;
            
        end

        % Button pushed function: 
        % NoiseremovalusingstructuralelementButton
        function NoiseremovalusingstructuralelementButtonPushed(app, event)

            % Create Progress bar
            app.NoiseremovalusingstructuralelementButton.Text = 'preprocessing...';
            app.NoiseremovalusingstructuralelementButton.IconAlignment = 'bottom';
            wbar = permute(repmat(app.NoiseremovalusingstructuralelementButton.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.NoiseremovalusingstructuralelementButton.Icon = wbar;


            app.SE_size_preMorpho = app.SEsizeEditField.Value; % to make sure the current value is used
            app.SEelement = app.DropDown.Value; % to make sure the current value is used
            
            for k = 1 : length(app.Raw_Images)

                % Update progress bar
                currentProg = min(round((size(wbar,2)-2)*(k/length(app.Raw_Images))),size(wbar,2)-2);
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.NoiseremovalusingstructuralelementButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);


                Image = app.Raw_Images{k};
                [row,col,dim]=size(Image);
                filt_im_ope=zeros(row,col,dim);     % final filtered data using opening morphology
                conn=8;
               
                SE_type=app.SEelement;
                
                switch  SE_type
                    case 'Disk'              % Structural Element  = Disk
                        R=app.SE_size_preMorpho;
                        % h=waitbar(0,'Image is being filtered using disk-shaped Stractural Element (SE)! Please wait...');
                        se = strel('disk',R);

                    case 'Circle'      % Structural Element  = Square
                        R=app.SE_size_preMorpho;
                        % h=waitbar(0,'Image is being filtered using circle-shaped Stractural Element (SE)! Please wait...');
                        se = strel('square',R);

                end

                for b=1:dim       % b = Index of each band of data
                    mask=Image(:,:,b);    % Each band of data as the input
                    marker_ope = imopen(mask, se);
                    filt_im_ope(:,:,b) = imreconstruct(marker_ope,mask,conn);   % filtered band "b" , using Radius size "R"
                end

                app.smoothed_by_Morpho_images{k} = filt_im_ope;
                imshow(filt_im_ope, [], 'Parent', app.Image_Input);
                
            end
            
            % remove progress bar
            app.NoiseremovalusingstructuralelementButton.Icon = '';
            app.NoiseremovalusingstructuralelementButton.Text = 'Noise removal using structural element';

            
            % clear app.Raw_Images to handle memory overflow, but does not do anything
            
            disp('Preprocessing finished!')

            % Disable previous buttons
            app.LoadrawimagesButton.Enable = 'off';
            
        end

        % Button pushed function: SegmentneuronsButton
        function SegmentneuronsButtonPushed(app, event)
           
            %% Deactivate previous steps 
            app.LoadrawimagesButton.Enable = 'off';
            app.NoiseremovalusingstructuralelementButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.SEsizeEditField.Enable = 'off';
            
                        
            %% Initialize pogress bar on 'Segment neurons' button
            app.SegmentneuronsButton.Text = 'Segmenting neurons...';
            app.SegmentneuronsButton.IconAlignment = 'bottom';
            wbar = permute(repmat(app.SegmentneuronsButton.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.SegmentneuronsButton.Icon = wbar;
            
            
            % Handle 'segmentation without preprocessing'
            if(isempty(app.smoothed_by_Morpho_images))
                app.Segmented_Images = cell(1,length(app.Raw_Images));
                app.smoothed_by_Morpho_images = app.Raw_Images;
            else
                app.Segmented_Images = cell(1,length(app.smoothed_by_Morpho_images));    
            end
            
          
            % Create directory for segmented images
            if ~exist('Segmented', 'dir')
                mkdir('Segmented')
            end
            
            % Create timing txt file
            cd Segmented
            fileID = fopen('SegmentTime.txt' , 'w');
            fprintf(fileID , '%20s  %20s  %20s \r\n' , 'NeuronIndex' , 'TimeStart', 'TimeEnd');
            fclose(fileID);
            cd(app.SaveDir);

            % Check status of user input variables
            app.gap_bridge_check = app.GapbridgingCheckBox.Value;
            app.gap_size_micron = app.maximalgapsizemEditField.Value;
            app.thickness_micron = app.NeuritethicknessmEditField.Value;
            
            %% Loop through images for segmentation
            for k = 1 : length(app.smoothed_by_Morpho_images)
                 
                % update progress bar (royal blue)
                currentProg = min(round((size(wbar,2)-2)*(k/length(app.Segmented_Images))),size(wbar,2)-2);
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.SegmentneuronsButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);
                
              
                % Convert neurite thickness and gap size to pixels
                thickness_pixel = round(app.thickness_micron / app.PixSizes{k});
                gap_size_pixel = round(app.gap_size_micron / app.PixSizes{k});
                
                % Start counter for segmentation timing
                TimestampStart = char(datetime('now'));            
                
                %% Segmentation               
                % v06: optional gap bridging
                Seg_Image = segment_FiberMet_v06_Function(app.smoothed_by_Morpho_images{k} , thickness_pixel, gap_size_pixel, app.gap_bridge_check);              
                app.Segmented_Images{k} = Seg_Image;
                
                % stop segmentation counter
                TimestampEnd = char(datetime('now'));

                
                % visualize segmentation
                imshow(app.smoothed_by_Morpho_images{k}, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
                imshow(Seg_Image, 'Parent', app.Image_Segmented);  % showing only the last segmented image

                % save timing results
                cd Segmented

                fileID = fopen('SegmentTime.txt' , 'a');
                fprintf(fileID , '%20s  %20s  %20s \r\n', app.TifFiles(k).name , TimestampStart, TimestampEnd);
                fclose(fileID);
                   
                % save segmented image as tif
                filename=strcat('Seg_',app.TifFiles(k).name);
                imwrite( app.Segmented_Images{k}, filename);
                cd(app.SaveDir);
                app.index = app.index + 1;


            end

            % remove progress bar
            app.SegmentneuronsButton.Icon = '';
            app.SegmentneuronsButton.Text = 'Segment neurons';


            disp('**********************************');
            disp('Image(s) has(have) been segmented!');
            disp('**********************************');

            msgbox('All images have been segmented! It is advisable to check the segmented images for artifacts or improper soma placement before proceeding to the classification.');
            
            % Activate subsequent steps
            app.previousButton.Enable = 'on';
            app.nextButton.Enable = 'on';
            app.excludeButton.Enable = 'on';
            app.FindsomaneuritesandbranchesButton.Enable = 'on';
            app.minimalBranchLengthEditField.Enable = 'on';
            
        end

        % Button pushed function: FindsomaneuritesandbranchesButton
        function FindsomaneuritesandbranchesButtonPushed(app, event)
            
            % Deactivate previous steps
            app.NoiseremovalusingstructuralelementButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.SEsizeEditField.Enable = 'off';
            app.SegmentneuronsButton.Enable = 'off';
            app.GapbridgingCheckBox.Enable = 'off';
            app.NeuritethicknessmEditField.Enable = 'off';
            app.maximalgapsizemEditField.Enable = 'off';
            app.previousButton.Enable = 'off';
            app.nextButton.Enable = 'off';
            app.excludeButton.Enable = 'off';
            app.minimalBranchLengthEditField.Enable = 'off';
            app.LoadsegmentedimagesButton.Enable = 'off';
            app.pixelsizepxmEditField.Enable = 'off';
            
            % Create progress bar for between neurons
            app.ClassificationButton.Text = 'Classifying neurons...';
            app.ClassificationButton.IconAlignment = 'bottom';
            wbar = permute(repmat(app.ClassificationButton.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.ClassificationButton.Icon = wbar;

      
            % If results-folder and file hasn't been pre-specified, do it now
            if ~exist(fullfile(app.SaveDir, 'Results'), 'dir')
                mkdir(fullfile(app.SaveDir, 'Results'))
                mkdir(fullfile(app.SaveDir, 'Results', 'Figures'))
%                 cd(fullfile(app.SaveDir, 'Results'))
%                 fileID = fopen('Results.txt' , 'w');
%                 fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , 'NeuronIndex' , 'TimeStart', 'TimeEnd',  'SkeletonLength', 'SomaSize' , 'AxonLength' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , 'BranchPointsNum' , 'PrimBranchNum' , 'PrimBranchLen', 'allBranchPoints', 'allEndPoints' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s' , 'SecBranchNum' , 'SecBranchLen', 'TertBranchNum' , 'TertBranchLen' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s' , 'DendriteNum' , 'DendriteLen', 'DendBranchNum' , 'DendBranchLen' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s %20s %20s %20s %20s \r\n' , 'RatioAxon2Skel' , 'RatioPrim2Skel', 'RatioSec2Skel' , 'RatioTer2Skel' , 'RatioDen2Skel' , 'RatioDenBr2Skel', 'TotalBranchLength', 'Comment');
%                 fclose(fileID);
%                 cd(app.SaveDir);
            end 
            
            
            %% Set Soma-detection variables
            s = 50; S = 50;
            tol = 10; extentThr = 0.25;

            
            for k = 1 : length(app.Segmented_Images)     % number of loaded segmented images
                
                % Calculate minimal branch length in pixels
                MIN_LEN = round(app.MIN_LEN_micron / app.PixSizes{k});
                disp(MIN_LEN)
                
                % update progress bar between images (royal blue)
                currentProgTotal = min(round((size(wbar,2)-2)*(k/length(app.Segmented_Images))),size(wbar,2)-2);
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 1) = 0.25391;
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 2) = 0.41016;
                app.ClassificationButton.Icon(2:end-1, 2:currentProgTotal+1, 3) = 0.87891;


                % Create progress bar for classification in individual image
                app.FindsomaneuritesandbranchesButton.Text = 'Finding soma...';
                app.FindsomaneuritesandbranchesButton.IconAlignment = 'bottom';
                wbar = permute(repmat(app.FindsomaneuritesandbranchesButton.BackgroundColor,15,1,200),[1,3,2]);
                wbar([1,end],:,:) = 0;
                wbar(:,[1,end],:) = 0;
                
                % Update progress bar to 'Finding soma'
                app.FindsomaneuritesandbranchesButton.Icon = wbar;
                app.FindsomaneuritesandbranchesButton.Text = 'Finding soma...';
                currentProg = min(round((size(wbar,2)-2)*(0/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;


                BW = im2bw(app.Segmented_Images{k}); % remove?
                
                %% Initialize results struct
                app.Classified_processes = struct;
                app.Classified_processes.Image_name = app.TifFiles(k).name;
                app.Classified_processes.Segmented_image = BW;
                app.Classified_processes.TimestampStart = char(datetime('now'));
                
                if size(app.smoothed_by_Morpho_images) > 0
                    raw = app.smoothed_by_Morpho_images{k};
                    imshow(raw, 'Parent', app.Image_Input, "DisplayRange", [0 150]);
                else 
                    raw = app.Segmented_Images{k};
                    imshow(raw, 'Parent', app.Image_Input);
                end


                % Clear figures (except previous final classification)                                
                imshow(BW, 'Parent', app.Image_Segmented);
                cla(app.Image_soma)
                cla(app.Image_axon)
                           

                disp('*********************************************************');
                disp(['Image Index : ' , num2str(k)]);
                disp('Soma is being extracted . . .');
                disp(' ');
                pause(0.05);
                
                
                I = 255*uint8(BW);
                [app.Classified_processes.cBody, app.Classified_processes.Initial_skeleton, center] = FindCellBody(I, tol, extentThr , s , S, round(1/app.PixSizes{k}));
                
                %% Error handling, if detected soma takes up more than 50% of image
                if (nnz(app.Classified_processes.cBody) > 0.5*nnz(BW))
                    disp('No soma detected, skip to next image')
                    cd Results
                    
                    app.Classified_processes.TimestampEnd = char(datetime('now'));
%                     fileID = fopen('Results.txt' , 'a');
%                     NA_id = char('NA');
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , app.TifFiles(k).name , app.Classified_processes.TimestampStart, app.Classified_processes.TimestampEnd, NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id, NA_id,  NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20s %20s \r\n' , 0 , 0 , 0 , 0 , 0 , 0, NA_id, 'No soma detected' );
%                     fclose(fileID);
%                     
                    app.per_neuron_results = summarize_per_neuron(app.per_neuron_results, app.Classified_processes, app.PixSizes{k}, k + app.excl_counter, 'no soma');
                    writetable(app.per_neuron_results, 'SummaryStats.csv') % just to not lose data in case of error in downstream iteration
                    cd(app.SaveDir);
                    
                elseif (nnz(app.Classified_processes.cBody) < 0.01*nnz(BW))
                    disp('Soma unrealistically small, skip to next image')
                    cd Results
                    
                    app.Classified_processes.TimestampEnd = char(datetime('now'));
%                     fileID = fopen('Results.txt' , 'a');
%                     NA_id = char('NA');
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , app.TifFiles(k).name , app.Classified_processes.TimestampStart, app.Classified_processes.TimestampEnd, NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id, NA_id,  NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20s %20s \r\n' , 0 , 0 , 0 , 0 , 0 , 0, NA_id, 'No soma detected' );
%                     fclose(fileID);
%                     
                    app.per_neuron_results = summarize_per_neuron(app.per_neuron_results, app.Classified_processes, app.PixSizes{k}, k + app.excl_counter, 'no soma');
                    writetable(app.per_neuron_results, 'SummaryStats.csv') % just to not lose data in case of error in downstream iteration
                    cd(app.SaveDir);
                else
                    
                
                disp(' ');
                disp('Soma is found! Next step (i.e. Axon Extraction) will start automatically!');
                disp(' ');
                
                somaImage = imoverlay(BW, app.Classified_processes.cBody, [0 0 1] );
                imshow(somaImage, 'Parent',app.Image_soma);

                %% Extract Axon
                disp(' ');
                disp('Trying to find the Axon, as longest branch coming out of the Soma . . .');
                disp(' ');

                % Update progress bar to 'Finding axon'
                app.FindsomaneuritesandbranchesButton.Text = 'Finding axon...';
                currentProg = min(round((size(wbar,2)-2)*(1/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                % Find axon and dendrites
                endPoints = find(bwmorph(app.Classified_processes.Initial_skeleton, 'endpoints'));
              
                try
                    [app.Classified_processes.Neurites, newSkel, app.Classified_processes.Axon, endPoints] = FindSomaNeurites(app.Classified_processes.Initial_skeleton, app.Classified_processes.cBody, endPoints, MIN_LEN);
                   
                catch  %% Error handling
                    disp('No axon detected, skip to next image')
                    cd Results
                    
                    app.Classified_processes.TimestampEnd = char(datetime('now'));
%                     fileID = fopen('Results.txt' , 'a');
%                     NA_id = char('NA');
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , app.TifFiles(k).name , app.Classified_processes.TimestampStart, app.Classified_processes.TimestampEnd, NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id, NA_id,  NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%                     fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20s %20s \r\n' , 0 , 0 , 0 , 0 , 0 , 0, NA_id, 'No axon detected' );
%                     fclose(fileID);
%                     
%                     cd(app.SaveDir);
                    
                    app.per_neuron_results = summarize_per_neuron(app.per_neuron_results, app.Classified_processes, app.PixSizes{k}, k + app.excl_counter, 'no neurites');
                    writetable(app.per_neuron_results, 'SummaryStats.csv') % just to not lose data in case of error in downstream iteration
                    cd(app.SaveDir);
                    continue
                end

                disp(' ');
                disp('Axon and Dendrites are found! Next step (i.e. Axon Branch Extraction) will start automatically!');
                disp(' ');
                
                % Visualize results
                image = BW;
                % Axon
                image = imoverlay(image, imdilate(app.Classified_processes.Axon{1}, ones(10)), [1 1 0] );

                % Neurites
                for j = 1:size(app.Classified_processes.Neurites)
                    image = imoverlay(image, imdilate(app.Classified_processes.Neurites{j}, ones(10)), [1 0 0] );
                end


                imshow(image, 'Parent',app.Image_axon);
                cla(app.Image_final_classified)


                %% Extract Branches of the axon
                % Update progress bar
                app.FindsomaneuritesandbranchesButton.Text = 'Finding axon branches...';
                currentProg = min(round((size(wbar,2)-2)*(2/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                app.Classified_processes.AxonBranches = cell(10,1);
                app.Classified_processes.AxonBranches{1} = app.Classified_processes.Axon;
                j = 1;
                while ~isempty(app.Classified_processes.AxonBranches{j}) || j > 10
                    disp(['Analysing branch order ', num2str(j)]);
                    j = j+1;
                    [app.Classified_processes.AxonBranches{j}, newSkel, endPoints] = findNextOrderBranch(newSkel, app.Classified_processes.AxonBranches{j-1}, endPoints, MIN_LEN);
                end

                disp('axon branches detected');

                %% Extract branches of dendrites
                % Update progress bar
                app.FindsomaneuritesandbranchesButton.Text = 'Finding dendrite branches...';
                currentProg = min(round((size(wbar,2)-2)*(3/4)),size(wbar,2)-2);
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.FindsomaneuritesandbranchesButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.05);

                app.Classified_processes.NeuriteBranches = cell(10,1);
                app.Classified_processes.NeuriteBranches{1} = app.Classified_processes.Neurites;
                j = 1;
                while ~isempty(app.Classified_processes.NeuriteBranches{j}) || j > 10
                    disp(['Analysing branch order ', num2str(j)]);
                    j = j+1;
                    [app.Classified_processes.NeuriteBranches{j}, newSkel, endPoints] = findNextOrderBranch(newSkel, app.Classified_processes.NeuriteBranches{j-1}, endPoints, MIN_LEN);
                end

                disp('dendrite branches detected');

                % NeuriteBranches = NeuriteBranches(~cellfun('isempty',NeuriteBranches));


                % Visualize Branches
                for l = 2:size(app.Classified_processes.AxonBranches)
                    %total = size(AxonBranches);
                    Branches = app.Classified_processes.AxonBranches{l};
                    for j = 1:size(Branches)
                        image = imoverlay(image, imdilate(Branches{j}, ones(10)), [0 1-mod(l-1,4)/3 1-mod(l-1,3)/4] );
                    end
                end

                for l = 2:size(app.Classified_processes.NeuriteBranches)
                    %total = size(NeuriteBranches);
                    Branches = app.Classified_processes.NeuriteBranches{l};
                    for j = 1:size(Branches)
                        image = imoverlay(image, imdilate(Branches{j}, ones(10)), [mod(l-1,4)/3 0 1-mod(l-1,3)/4] );
                    end
                end

                imshow(image, 'Parent',app.Image_final_classified); 


%                 %% calculating summaries per neuron  
%                 sklI_noSoma = app.Classified_processes.Initial_skeleton .* (~ bwmorph(app.Classified_processes.cBody,'thin',1));
%                 total_neurite_length = app.PixSizes{k} * nnz(sklI_noSoma);
%                 soma_size = app.PixSizes{k} * app.PixSizes{k} * nnz(app.Classified_processes.cBody);
% 
%                 axon_length = app.PixSizes{k} * nnz(app.Classified_processes.Axon{1});
%                 prim_branch_points = size(app.Classified_processes.AxonBranches{2});
%                 prim_branch_length = app.PixSizes{k} * nnz(cell2mat(app.Classified_processes.AxonBranches{2}));
%                 sec_branch_points = size(app.Classified_processes.AxonBranches{3});
%                 sec_branch_length = app.PixSizes{k} * nnz(cell2mat(app.Classified_processes.AxonBranches{3}));
%                 tert_branch_points = size(app.Classified_processes.AxonBranches{4});
%                 tert_branch_length = app.PixSizes{k} * nnz(cell2mat(app.Classified_processes.AxonBranches{4}));
% 
%                 axon_branch_points = 0;
%                 axon_branch_length = 0;
%                 for l = 2:size(app.Classified_processes.AxonBranches)
%                     num = size(app.Classified_processes.AxonBranches{l});
%                     axon_branch_points = axon_branch_points + num(1);
%                     len = nnz(cell2mat(app.Classified_processes.AxonBranches{l}));
%                     axon_branch_length = axon_branch_length + len;
%                 end
%                 axon_branch_length = axon_branch_length * app.PixSizes{k};
% 
%                 dendrite_num = size(app.Classified_processes.Neurites);
%                 dendrite_length = app.PixSizes{k} * nnz(cell2mat(app.Classified_processes.Neurites));
% 
%                 dendrite_branch_points = 0;
%                 dendrite_branch_length = 0;
%                 for l = 2:size(app.Classified_processes.NeuriteBranches)
%                     num = size(app.Classified_processes.NeuriteBranches{l});
%                     dendrite_branch_points = dendrite_branch_points + num(1);
%                     len = nnz(cell2mat(app.Classified_processes.NeuriteBranches{l}));
%                     dendrite_branch_length = dendrite_branch_length + len;
%                 end
%                 dendrite_branch_length = dendrite_branch_length * app.PixSizes{k};

                %% Saving as .txt  
                app.Classified_processes.TimestampEnd = char(datetime('now'));
                cd Results
%                 fileID = fopen('Results.txt' , 'a');
% 
%                 fprintf(fileID , '%20s  %20s  %20s  %20d  %20d  %20d' , app.TifFiles(k).name , app.Classified_processes.TimestampStart, app.Classified_processes.TimestampEnd, total_neurite_length , soma_size , axon_length);
%                 fprintf(fileID , '%20d  %20d  %20d  %20d  %20d' , axon_branch_points , prim_branch_points(1) , prim_branch_length, 0,  nnz(endPoints));
%                 fprintf(fileID , '%20d  %20d  %20d  %20d' , sec_branch_points(1) , sec_branch_length , tert_branch_points(1) , tert_branch_length);
%                 fprintf(fileID , '%20d  %20d  %20d  %20d' , dendrite_num(1) , dendrite_length , dendrite_branch_points , dendrite_branch_length);
%                 fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20d %20s \r\n' , 0 , 0 , 0 , 0 , 0 , 0, axon_branch_length, '' );
% 
%                 fclose(fileID);
                
                
                app.per_neuron_results = summarize_per_neuron(app.per_neuron_results, app.Classified_processes, app.PixSizes{k}, k + app.excl_counter, 'all fine');
                writetable(app.per_neuron_results, 'SummaryStats.csv') % just to not lose data in case of error in downstream iteration
                

                disp('Quantitative measurements can be found within a csv file (SummaryStats.csv) located in the folder "Results"!');
                disp(' ');

                %% Save all figures
                disp(' ');
                disp('All figures are saved in the "Results\Figures" folder!');
                cd Figures

                filename=strcat('Class_',app.TifFiles(k).name,'.tif');
                imwrite(image, filename);

                cd(app.SaveDir);
                pause(0.5);
                end
            end
            cd Results
            writetable(app.per_neuron_results, 'SummaryStats.csv')
            writetable(app.per_neuron_results, 'SummaryStats.xlsx')

            cd(app.WorkDir);
            
            % remove waitbar
            app.FindsomaneuritesandbranchesButton.Icon = '';
            app.ClassificationButton.Icon = '';
            % Change button name
            app.FindsomaneuritesandbranchesButton.Text = 'Find soma, neurites and branches';
            app.ClassificationButton.Text = 'Classification';
            
            disp('******************************');
            disp('DONE');
            msgbox('Classification finished. Check the quality of classification in the Results/Figures folder and proceed with the summarized per neuron measurements in Results/SummaryStats.csv')


        end

        % Value changed function: minimalBranchLengthEditField
        function minimalbranchlengthEditFieldValueChanged(app, event)
            app.MIN_LEN_micron = app.minimalBranchLengthEditField.Value;

        end

        % Button pushed function: LoadsegmentedimagesButton
        function LoadsegmentedimagesButtonPushed(app, event)
            
            % Load pre-segmented images
            [~, pNameSeg] = uigetfile('*.tif');
            app.SaveDir = pNameSeg;
            cd(app.SaveDir);
            app.TifFiles = dir('*.tif');
            numfiles = length(app.TifFiles);
            app.Segmented_Images = cell(1, numfiles);
            app.pix_size = app.pixelsizepxmEditField.Value;
            
            for k = 1:numfiles
                app.Segmented_Images{k} = imread(app.TifFiles(k).name);
                imshow(app.Segmented_Images{k}, 'Parent', app.Image_Segmented); 
                app.PixSizes{k} = app.pix_size;
            end
            % save('TifFiles.mat','TifFiles');

            disp('**********************************');
            disp('Segmented images(s) has(have) been uploaded!');
            disp('**********************************');

         
            % create Results folder and file
            if ~exist(fullfile(app.SaveDir, 'Results'), 'dir')
                    mkdir(fullfile(app.SaveDir, 'Results'))
                    mkdir(fullfile(app.SaveDir, 'Results', 'Figures'))
            end
%             cd(fullfile(app.SaveDir, 'Results'))
%             fileID = fopen('Results.txt' , 'w');
%             fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , 'NeuronIndex' , 'TimeStart', 'TimeEnd',  'SkeletonLength', 'SomaSize' , 'AxonLength' );
%             fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , 'BranchPointsNum' , 'PrimBranchNum' , 'PrimBranchLen', 'allBranchPoints', 'allEndPoints' );
%             fprintf(fileID , '%20s  %20s  %20s  %20s' , 'SecBranchNum' , 'SecBranchLen', 'TertBranchNum' , 'TertBranchLen' );
%             fprintf(fileID , '%20s  %20s  %20s  %20s' , 'DendriteNum' , 'DendriteLen', 'DendBranchNum' , 'DendBranchLen' );
%             fprintf(fileID , '%20s  %20s  %20s  %20s %20s %20s %20s %20s \r\n' , 'RatioAxon2Skel' , 'RatioPrim2Skel', 'RatioSec2Skel' , 'RatioTer2Skel' , 'RatioDen2Skel' , 'RatioDenBr2Skel', 'TotalBranchLength', 'Comment');
%             fclose(fileID);
%             cd(app.SaveDir);
            
            % activate subsequent steps
            app.FindsomaneuritesandbranchesButton.Enable = 'on';
            app.minimalBranchLengthEditField.Enable = 'on';
            app.LoadrawimagesButton.Enable = 'off';
            
        end

        % Value changed function: GapbridgingCheckBox
        function GapbridgingCheckBoxValueChanged(app, event)
            app.gap_bridge_check = app.GapbridgingCheckBox.Value;
                        
        end

        % Value changed function: maximalgapsizemEditField
        function maximalgapsizemEditFieldValueChanged(app, event)
            app.gap_size_micron = app.maximalgapsizemEditField.Value;
            
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            if (app.index > 1)
                app.index = app.index-1;
            end
            disp(app.index);
            imshow(app.smoothed_by_Morpho_images{app.index}, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
            imshow(app.Segmented_Images{app.index}, 'Parent', app.Image_Segmented);  % showing only the last segmented image

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if (app.index < length(app.Segmented_Images))
                app.index = app.index+1;
            end
            disp(app.index);
            imshow(app.smoothed_by_Morpho_images{app.index}, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
            imshow(app.Segmented_Images{app.index}, 'Parent', app.Image_Segmented);  % showing only the last segmented image
 
        end

        % Button pushed function: resetallButton
        function resetallButtonPushed(app, event)
          %% For now just restart the app -> everything is wiped and does not have to be taken care off explicitly 
          cd(app.WorkDir);
          
          % Make current instance of app invisible
          app.UIFigure.Visible = 'off';
          
          % Open 2nd instance of app
          Neuron_Classifier2();
          
          % Delete old instance
          close(app.UIFigure)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            exit
        end

        % Button pushed function: excludeButton
        function excludeButtonPushed(app, event)

            % add a flag to excluded segmented images
            cd Segmented
            if ~exist('Excluded', 'dir')
                mkdir('Excluded')
                if ~exist(fullfile(app.SaveDir, 'Results'), 'dir')
                    mkdir(fullfile(app.SaveDir, 'Results'))
                    mkdir(fullfile(app.SaveDir, 'Results', 'Figures'))
                end
%                 cd(fullfile(app.SaveDir, 'Results'))
%                 fileID = fopen('Results.txt' , 'w');
%                 fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , 'NeuronIndex' , 'TimeStart', 'TimeEnd',  'SkeletonLength', 'SomaSize' , 'AxonLength' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , 'BranchPointsNum' , 'PrimBranchNum' , 'PrimBranchLen', 'allBranchPoints', 'allEndPoints' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s' , 'SecBranchNum' , 'SecBranchLen', 'TertBranchNum' , 'TertBranchLen' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s' , 'DendriteNum' , 'DendriteLen', 'DendBranchNum' , 'DendBranchLen' );
%                 fprintf(fileID , '%20s  %20s  %20s  %20s %20s %20s %20s %20s \r\n' , 'RatioAxon2Skel' , 'RatioPrim2Skel', 'RatioSec2Skel' , 'RatioTer2Skel' , 'RatioDen2Skel' , 'RatioDenBr2Skel', 'TotalBranchLength', 'Comment');
%                 fclose(fileID);
%                 cd(fullfile(app.SaveDir, 'Segmented'))
            end
            filename = strcat('Seg_',app.TifFiles(app.index).name);
            new_filename = strcat('Excl_Seg_',app.TifFiles(app.index).name);
            movefile(filename, fullfile("Excluded", new_filename));
            disp('Image excluded');
            cd(app.SaveDir);
            
            
            % make a note in the results file
            cd(fullfile(app.SaveDir, 'Results'))
%             fileID = fopen('Results.txt' , 'a');
% 
%             NA_id = char('NA');
%             fprintf(fileID , '%20s  %20s  %20s  %20s  %20s  %20s' , app.TifFiles(app.index).name , NA_id, NA_id, NA_id , NA_id , NA_id);
%             fprintf(fileID , '%20s  %20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id, NA_id,  NA_id);
%             fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%             fprintf(fileID , '%20s  %20s  %20s  %20s' , NA_id , NA_id , NA_id , NA_id);
%             fprintf(fileID , '%20.1f  %20.1f  %20.1f  %20.1f %20.1f %20.1f %20s %20s \r\n' , 0 , 0 , 0 , 0 , 0 , 0, NA_id, 'Excluded after segmentation' );
%             fclose(fileID);
            
            app.excl_counter = app.excl_counter + 1; % to append results in table rather than overwriting
            %% Initialize results struct
            app.Classified_processes = struct;
            app.Classified_processes.Image_name = app.TifFiles(app.index).name;
            app.per_neuron_results = summarize_per_neuron(app.per_neuron_results, app.Classified_processes, app.PixSizes{app.index}, app.excl_counter, 'excluded');
            writetable(app.per_neuron_results, 'SummaryStats.csv') % just to not lose data in case of error in downstream iteration
            
            cd(app.SaveDir)

            
            % remove from relevant arrays
            app.TifFiles(app.index) = [];
            app.Raw_Images(app.index) = [];
            app.smoothed_by_Morpho_images(app.index) = [];
            app.Segmented_Images(app.index) = [];
            app.PixSizes(app.index) = [];


            % show next image
            if (isempty(app.Segmented_Images))
                cla(app.Image_Input);
                cla(app.Image_Segmented);
                msgbox('All images have been excluded')
                
            elseif (app.index > 1)
                % show next image
                app.index = app.index -1;
                imshow(app.smoothed_by_Morpho_images{app.index}, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
                imshow(app.Segmented_Images{app.index}, 'Parent', app.Image_Segmented);  % showing only the last segmented image
            
            else
                % show last image
                imshow(app.smoothed_by_Morpho_images{app.index}, [] , 'Parent', app.Image_Input);  % showing only the last segmented image
                imshow(app.Segmented_Images{app.index}, 'Parent', app.Image_Segmented);  % showing only the last segmented image                   
            end
            
        end

        % Value changed function: pixelsizepxmEditField
        function pixelsizepxmEditFieldValueChanged(app, event)
            app.pix_size = app.pixelsizepxmEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {732, 732};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {606, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.Image_Input = uiaxes(app.LeftGrid);
            title(app.Image_Input, 'Input image')
            app.Image_Input.XTick = [];
            app.Image_Input.YTick = [];
            app.Image_Input.Visible = 'off';
            app.Image_Input.Tag = 'Input_image';
            app.Image_Segmented = uiaxes(app.LeftGrid);
            title(app.Image_Segmented, 'Segmented image')
            app.Image_Segmented.XTick = [];
            app.Image_Segmented.YTick = [];
            app.Image_Segmented.Visible = 'off';
            app.Image_Segmented.Tag = 'Input_image';
            app.SegmentationLabel = uilabel(app.LeftGrid);
            app.SegmentationLabel.Text = 'Segmentation';
            app.PreprocessingGrid = uigridlayout(app.LeftGrid);
            app.NoiseremovalusingstructuralelementButton = uibutton(app.PreprocessingGrid, 'push');
            app.NoiseremovalusingstructuralelementButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseremovalusingstructuralelementButtonPushed, true);
            app.NoiseremovalusingstructuralelementButton.Enable = 'off';
            app.NoiseremovalusingstructuralelementButton.Text = 'Noise removal using structural element';
            app.SEsizeEditFieldLabel = uilabel(app.PreprocessingGrid);
            app.SEsizeEditFieldLabel.Text = 'SE size';
            app.SEsizeEditField = uieditfield(app.PreprocessingGrid, 'numeric');
            app.SEsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SEsizeEditFieldValueChanged, true);
            app.SEsizeEditField.Enable = 'off';
            app.SEsizeEditField.Value = 2;
            app.DropDown = uidropdown(app.PreprocessingGrid);
            app.DropDown.Items = {'Circle', 'Disk'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Circle';
            app.SegmentationGrid = uigridlayout(app.LeftGrid);
            app.SegmentneuronsButton = uibutton(app.SegmentationGrid, 'push');
            app.SegmentneuronsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentneuronsButtonPushed, true);
            app.SegmentneuronsButton.Enable = 'off';
            app.SegmentneuronsButton.Text = 'Segment neurons';
            app.GapbridgingCheckBox = uicheckbox(app.SegmentationGrid);
            app.GapbridgingCheckBox.ValueChangedFcn = createCallbackFcn(app, @GapbridgingCheckBoxValueChanged, true);
            app.GapbridgingCheckBox.Enable = 'off';
            app.GapbridgingCheckBox.Text = 'Gap bridging';
            app.GapbridgingCheckBox.Value = true;
            app.LoadrawimagesButton = uibutton(app.LeftGrid, 'push');
            app.LoadrawimagesButton.ButtonPushedFcn = createCallbackFcn(app, @load_raw_images, true);
            app.LoadrawimagesButton.Tooltip = {'Specify the folder with 8-bit one-channel images of neurons'};
            app.LoadrawimagesButton.Text = 'Load raw images';
            app.PreprocessingLabel = uilabel(app.LeftGrid);
            app.PreprocessingLabel.Text = 'Preprocessing';
            app.NeuronsegmenterLabel = uilabel(app.LeftGrid);
            app.NeuronsegmenterLabel.Text = 'Neuron segmenter';
            app.GridLayout2 = uigridlayout(app.LeftGrid);
            app.NeuritethicknessmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NeuritethicknessmEditField.Limits = [0 Inf];
            app.NeuritethicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @NeuritethicknessmEditFieldValueChanged, true);
            app.NeuritethicknessmEditField.Enable = 'off';
            app.NeuritethicknessmEditField.Value = 1;
            app.NeuritethicknessmEditFieldLabel = uilabel(app.GridLayout2);
            app.NeuritethicknessmEditFieldLabel.Text = 'Neurite thickness [µm]';
            app.maximalgapsizemEditFieldLabel = uilabel(app.GridLayout2);
            app.maximalgapsizemEditFieldLabel.Text = 'maximal gap size [µm]';
            app.maximalgapsizemEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maximalgapsizemEditField.Limits = [0 Inf];
            app.maximalgapsizemEditField.ValueChangedFcn = createCallbackFcn(app, @maximalgapsizemEditFieldValueChanged, true);
            app.maximalgapsizemEditField.Enable = 'off';
            app.maximalgapsizemEditField.Value = 6;
            app.GridLayout3 = uigridlayout(app.LeftGrid);
            app.previousButton = uibutton(app.GridLayout3, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Enable = 'off';
            app.previousButton.Text = '< previous';
            app.nextButton = uibutton(app.GridLayout3, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Enable = 'off';
            app.nextButton.Text = 'next >';
            app.excludeButton = uibutton(app.GridLayout3, 'push');
            app.excludeButton.ButtonPushedFcn = createCallbackFcn(app, @excludeButtonPushed, true);
            app.excludeButton.Enable = 'off';
            app.excludeButton.Text = 'exclude';
            app.resetallButton = uibutton(app.GridLayout3, 'push');
            app.resetallButton.ButtonPushedFcn = createCallbackFcn(app, @resetallButtonPushed, true);
            app.resetallButton.Text = {'reset all'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.ClassificationGrid = uigridlayout(app.RightPanel);
            app.Image_final_classified = uiaxes(app.ClassificationGrid);
            title(app.Image_final_classified, 'Final classification')
            app.Image_final_classified.XTick = [];
            app.Image_final_classified.YTick = [];
            app.Image_final_classified.Visible = 'off';
            app.Image_final_classified.Tag = 'Input_image';
            app.SomaAxonGrid = uigridlayout(app.ClassificationGrid);
            app.Image_axon = uiaxes(app.SomaAxonGrid);
            title(app.Image_axon, 'Axon & Dendrites')
            app.Image_axon.XTick = [];
            app.Image_axon.YTick = [];
            app.Image_axon.Visible = 'off';
            app.Image_axon.Tag = 'Input_image';
            app.Image_soma = uiaxes(app.SomaAxonGrid);
            title(app.Image_soma, 'Soma')
            app.Image_soma.XTick = [];
            app.Image_soma.YTick = [];
            app.Image_soma.Visible = 'off';
            app.Image_soma.Tag = 'Input_image';
            app.FindBranchesGrid = uigridlayout(app.ClassificationGrid);
            app.minimalbranchlengthmEditFieldLabel = uilabel(app.FindBranchesGrid);
            app.minimalbranchlengthmEditFieldLabel.Text = 'minimal branch length [µm]';
            app.minimalBranchLengthEditField = uieditfield(app.FindBranchesGrid, 'numeric');
            app.minimalBranchLengthEditField.Limits = [0 Inf];
            app.minimalBranchLengthEditField.ValueChangedFcn = createCallbackFcn(app, @minimalbranchlengthEditFieldValueChanged, true);
            app.minimalBranchLengthEditField.Enable = 'off';
            app.minimalBranchLengthEditField.Value = 10;
            app.FindsomaneuritesandbranchesButton = uibutton(app.FindBranchesGrid, 'push');
            app.FindsomaneuritesandbranchesButton.ButtonPushedFcn = createCallbackFcn(app, @FindsomaneuritesandbranchesButtonPushed, true);
            app.FindsomaneuritesandbranchesButton.Enable = 'off';
            app.FindsomaneuritesandbranchesButton.Text = 'Find soma, neurites and branches';
            app.LoadSegmentedGrid = uigridlayout(app.ClassificationGrid);
            app.pixelsizepxmEditField = uieditfield(app.LoadSegmentedGrid, 'numeric');
            app.pixelsizepxmEditField.Limits = [0 Inf];
            app.pixelsizepxmEditField.ValueChangedFcn = createCallbackFcn(app, @pixelsizepxmEditFieldValueChanged, true);
            app.pixelsizepxmEditField.Value = 0.2261;
            app.pixelsizepxmEditFieldLabel = uilabel(app.LoadSegmentedGrid);
            app.pixelsizepxmEditFieldLabel.Text = 'pixel size [px/µm]';
            app.LoadsegmentedimagesButton = uibutton(app.LoadSegmentedGrid, 'push');
            app.LoadsegmentedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsegmentedimagesButtonPushed, true);
            app.LoadsegmentedimagesButton.Text = 'Load segmented images';
            app.NeuronclassifierLabel = uilabel(app.ClassificationGrid);
            app.NeuronclassifierLabel.Text = 'Neuron classifier';
            app.ClassificationButton = uibutton(app.ClassificationGrid, 'push');
            app.ClassificationButton.Text = 'Classification';
            app.GridLayout4 = uigridlayout(app.ClassificationGrid);
            app.CloseButton = uibutton(app.GridLayout4, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neuron_Classifier2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end